classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ProcessedImagePanel  matlab.ui.container.Panel
        FourierButton_2      matlab.ui.control.Button
        SaveImgButton        matlab.ui.control.Button
        HistButton_2         matlab.ui.control.Button
        ProcessedImage       matlab.ui.control.UIAxes
        OriginalImagePanel   matlab.ui.container.Panel
        FourierButton        matlab.ui.control.Button
        HistButton           matlab.ui.control.Button
        OriginalImage        matlab.ui.control.UIAxes
        OptionsPanel         matlab.ui.container.Panel
        kEditField           matlab.ui.control.NumericEditField
        kEditFieldLabel      matlab.ui.control.Label
        ResetButton          matlab.ui.control.Button
        FilenamelabelLabel   matlab.ui.control.Label
        ProcessButton        matlab.ui.control.Button
        ChooseImageButton    matlab.ui.control.Button
        ModeDropDown         matlab.ui.control.DropDown
        ModeDropDownLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global img;
            global modifiedImg;
            img = -1;
            modifiedImg = -1;
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
           global img;
           global modifiedImg
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           app.FilenamelabelLabel.Text = filename;
           filename = strcat(pathname, filename);
           img = imread(filename);
           modifiedImg = img;
           imshow(img, 'Parent', app.OriginalImage);
        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            value = app.ModeDropDown.Value;
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global modifiedImg;
            global img;
            k = app.kEditField.Value;
            value = app.ModeDropDown.Value;
            if value == "Laplace"
                modifiedImg = laplace(modifiedImg);
            elseif value == "LoG"
                modifiedImg = logoperation(modifiedImg);
            elseif value == "Prewitt"
                modifiedImg = prewitt(modifiedImg);
            elseif value == "Robert"
                modifiedImg = robert(modifiedImg);
            elseif value == "Sobel"
                modifiedImg = sobel(modifiedImg);
            elseif value == "Canny"
                modifiedImg = canny(modifiedImg);
            elseif value == "Segment Object"
                modifiedImg = objectSegment(img, modifiedImg, k);
            elseif value == "Gaussian Blur"
                modifiedImg = gaussianBlur(modifiedImg);
            elseif value == "Morphological Close"
                modifiedImg = morphologicalClose(modifiedImg,k);
            elseif value == "Dilation"
                modifiedImg = dilation(modifiedImg,k);
            elseif value == "Erosion"
                modifiedImg = erosion(modifiedImg,k);
            end
            imshow(modifiedImg, 'Parent', app.ProcessedImage);
        end

        % Callback function
        function ImageReferenceButtonPushed(app, event)
           global ref;
           [filename, pathname] = uigetfile('*.*', 'pick an image');
           filename = strcat(pathname, filename);
           ref = imread(filename);
           app.FilenameLabel.Text = strcat("Filename : ", filename);
           
        end

        % Clicked callback: ModeDropDown
        function ModeDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Button pushed function: HistButton
        function HistButtonPushed(app, event)
            global img;
            histimg = ImProcTools.getHistImg(img);
        end

        % Button pushed function: FourierButton
        function FourierButtonPushed(app, event)
            global img;
            fourier = ImProcTools.showFourierImg(img, 1);
        end

        % Button pushed function: HistButton_2
        function HistButton_2Pushed(app, event)
            global modifiedImg;
            histimg = ImProcTools.getHistImg(modifiedImg);
        end

        % Button pushed function: FourierButton_2
        function FourierButton_2Pushed(app, event)
            global modifiedImg;
            fourier = ImProcTools.showFourierImg(modifiedImg, 1);
        end

        % Button pushed function: SaveImgButton
        function SaveImgButtonPushed(app, event)
            global modifiedImg;
            imwrite(modifiedImg, "result.png");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global modifiedImg;
            global img;
            modifiedImg = img;
            imshow(img, 'Parent', app.OriginalImage);
            imshow(0, 'Parent', app.ProcessedImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ModeDropDownLabel = uilabel(app.OptionsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.OptionsPanel);
            app.ModeDropDown.Items = {'Laplace', 'LoG', 'Prewitt', 'Robert', 'Sobel', 'Canny', 'Gaussian Blur', 'Segment Object', 'Morphological Close', 'Dilation', 'Erosion'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.ClickedFcn = createCallbackFcn(app, @ModeDropDownClicked, true);
            app.ModeDropDown.Value = 'Laplace';
            app.ChooseImageButton = uibutton(app.OptionsPanel, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.ProcessButton = uibutton(app.OptionsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.FilenamelabelLabel = uilabel(app.OptionsPanel);
            app.FilenamelabelLabel.Text = 'Filename label';
            app.ResetButton = uibutton(app.OptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.kEditFieldLabel = uilabel(app.OptionsPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.kEditField.Value = 11;
            app.OriginalImagePanel = uipanel(app.UIFigure);
            app.OriginalImagePanel.Title = 'Original Image';
            app.OriginalImage = uiaxes(app.OriginalImagePanel);
            title(app.OriginalImage, 'Image')
            xlabel(app.OriginalImage, 'X')
            ylabel(app.OriginalImage, 'Y')
            zlabel(app.OriginalImage, 'Z')
            app.HistButton = uibutton(app.OriginalImagePanel, 'push');
            app.HistButton.ButtonPushedFcn = createCallbackFcn(app, @HistButtonPushed, true);
            app.HistButton.Text = 'Hist';
            app.FourierButton = uibutton(app.OriginalImagePanel, 'push');
            app.FourierButton.ButtonPushedFcn = createCallbackFcn(app, @FourierButtonPushed, true);
            app.FourierButton.Text = 'Fourier';
            app.ProcessedImagePanel = uipanel(app.UIFigure);
            app.ProcessedImagePanel.Title = 'Processed Image';
            app.ProcessedImage = uiaxes(app.ProcessedImagePanel);
            title(app.ProcessedImage, 'Image')
            xlabel(app.ProcessedImage, 'X')
            ylabel(app.ProcessedImage, 'Y')
            zlabel(app.ProcessedImage, 'Z')
            app.HistButton_2 = uibutton(app.ProcessedImagePanel, 'push');
            app.HistButton_2.ButtonPushedFcn = createCallbackFcn(app, @HistButton_2Pushed, true);
            app.HistButton_2.Text = 'Hist';
            app.SaveImgButton = uibutton(app.ProcessedImagePanel, 'push');
            app.SaveImgButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImgButtonPushed, true);
            app.SaveImgButton.Text = 'Save Img';
            app.FourierButton_2 = uibutton(app.ProcessedImagePanel, 'push');
            app.FourierButton_2.ButtonPushedFcn = createCallbackFcn(app, @FourierButton_2Pushed, true);
            app.FourierButton_2.Text = 'Fourier';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end